classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HISBmodelSimulatorUIFigure      matlab.ui.Figure
        InitialParametersPanel          matlab.ui.container.Panel
        SimTimeSliderLabel              matlab.ui.control.Label
        SimTime                         matlab.ui.control.Slider
        InitialSpreadersEditFieldLabel  matlab.ui.control.Label
        IntSpeaders                     matlab.ui.control.NumericEditField
        IndividualsbackgroundknowledgePanel  matlab.ui.container.Panel
        MinSliderLabel                  matlab.ui.control.Label
        MinSlider                       matlab.ui.control.Slider
        MaxSliderLabel                  matlab.ui.control.Label
        MaxSlider                       matlab.ui.control.Slider
        ForgettingremmemberingfactorPanel  matlab.ui.container.Panel
        MinSlider_2Label                matlab.ui.control.Label
        MinSlider_2                     matlab.ui.control.Slider
        MaxSlider_2Label                matlab.ui.control.Label
        MaxSlider_2                     matlab.ui.control.Slider
        HesitatingfactorPanel           matlab.ui.container.Panel
        MinSlider_3Label                matlab.ui.control.Label
        MinSlider_3                     matlab.ui.control.Slider
        MaxSlider_3Label                matlab.ui.control.Label
        MaxSlider_3                     matlab.ui.control.Slider
        subjectivejudgmentPanel         matlab.ui.container.Panel
        MinLabel                        matlab.ui.control.Label
        DisMin                          matlab.ui.control.Slider
        MaxLabel                        matlab.ui.control.Label
        DisMax                          matlab.ui.control.Slider
        SimulationOffLampLabel          matlab.ui.control.Label
        SimStat                         matlab.ui.control.Lamp
        SimS                            matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IndividualsattrctionTab         matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        IndividualsOpinionTab           matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        DenyingOpinionLampLabel         matlab.ui.control.Label
        DenyingOpinionLamp              matlab.ui.control.Lamp
        SupprottingopinionLampLabel     matlab.ui.control.Label
        SupprottingopinionLamp          matlab.ui.control.Lamp
        ResultsPanel                    matlab.ui.container.Panel
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes7_3                       matlab.ui.control.UIAxes
        UIAxes7_4                       matlab.ui.control.UIAxes
        NetworkdetailPanel              matlab.ui.container.Panel
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.EditField
        NumberofEdgeEditFieldLabel      matlab.ui.control.Label
        NumberofEdgeEditField           matlab.ui.control.EditField
        KEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.EditField
        NetworknameEditFieldLabel       matlab.ui.control.Label
        NetworknameEditField            matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RestButton                      matlab.ui.control.Button
    end


    properties (Access = public)
        Stop=0; % Description
        Ready=1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load(app.NetworknameEditField.Value);
            app.NumberofNodesEditField.Value=num2str(numedges(G));
            app.NumberofEdgeEditField.Value= num2str(numnodes(G));
            app.KEditField.Value=num2str(numnodes(G)/numedges(G));
            n=numnodes(G);
            c=randi([0,0],n,1);
            c(:,2)=randi([0,0],n,1);
            c(:,3)=randi([1,1],n,1);
            
            
            app.UIAxes7.DataAspectRatioMode='auto';
            app.UIAxes7.PlotBoxAspectRatioMode='auto';
            app.UIAxes7.CLimMode='auto';
            app.UIAxes7.XLimMode='auto';
            app.UIAxes7.YLimMode='auto';
            app.UIAxes7.ZLimMode='auto';
            app.UIAxes7.GridAlphaMode='auto';
            app.UIAxes7.GridColorMode='auto';
            app.UIAxes7.TickDirMode='auto';
            app.UIAxes7.XTickMode='auto';
            app.UIAxes7.YTickMode='auto';
            app.UIAxes7.ZTickMode='auto';
            app.UIAxes7.XTickLabelMode='auto';
            app.UIAxes7.YTickLabelMode='auto';
            app.UIAxes7.ZTickLabelMode='auto';
            app.UIAxes7.CameraPositionMode='auto';
            app.UIAxes7.CameraTargetMode='auto';
            app.UIAxes7.CameraUpVectorMode='auto';
            
            app.UIAxes6.DataAspectRatioMode='auto';
            app.UIAxes6.PlotBoxAspectRatioMode='auto';
            app.UIAxes6.CLimMode='auto';
            app.UIAxes6.XLimMode='auto';
            app.UIAxes6.YLimMode='auto';
            app.UIAxes6.ZLimMode='auto';
            app.UIAxes6.GridAlphaMode='auto';
            app.UIAxes6.GridColorMode='auto';
            app.UIAxes6.TickDirMode='auto';
            app.UIAxes6.XTickMode='auto';
            app.UIAxes6.YTickMode='auto';
            app.UIAxes6.ZTickMode='auto';
            app.UIAxes6.XTickLabelMode='auto';
            app.UIAxes6.YTickLabelMode='auto';
            app.UIAxes6.ZTickLabelMode='auto';
            app.UIAxes6.CameraPositionMode='auto';
            app.UIAxes6.CameraTargetMode='auto';
            app.UIAxes6.CameraUpVectorMode='auto';
            
            app.UIAxes7_2.DataAspectRatioMode='auto';
            app.UIAxes7_2.PlotBoxAspectRatioMode='auto';
            app.UIAxes7_2.CLimMode='auto';
            app.UIAxes7_2.XLimMode='auto';
            app.UIAxes7_2.YLimMode='auto';
            app.UIAxes7_2.ZLimMode='auto';
            app.UIAxes7_2.GridAlphaMode='auto';
            app.UIAxes7_2.GridColorMode='auto';
            app.UIAxes7_2.TickDirMode='auto';
            app.UIAxes7_2.XTickMode='auto';
            app.UIAxes7_2.YTickMode='auto';
            app.UIAxes7_2.ZTickMode='auto';
            app.UIAxes7_2.XTickLabelMode='auto';
            app.UIAxes7_2.YTickLabelMode='auto';
            app.UIAxes7_2.ZTickLabelMode='auto';
            app.UIAxes7_2.CameraPositionMode='auto';
            app.UIAxes7_2.CameraTargetMode='auto';
            app.UIAxes7_2.CameraUpVectorMode='auto';
            
            app.UIAxes7_3.DataAspectRatioMode='auto';
            app.UIAxes7_3.PlotBoxAspectRatioMode='auto';
            app.UIAxes7_3.CLimMode='auto';
            app.UIAxes7_3.XLimMode='auto';
            app.UIAxes7_3.YLimMode='auto';
            app.UIAxes7_3.ZLimMode='auto';
            app.UIAxes7_3.GridAlphaMode='auto';
            app.UIAxes7_3.GridColorMode='auto';
            app.UIAxes7_3.TickDirMode='auto';
            app.UIAxes7_3.XTickMode='auto';
            app.UIAxes7_3.YTickMode='auto';
            app.UIAxes7_3.ZTickMode='auto';
            app.UIAxes7_3.XTickLabelMode='auto';
            app.UIAxes7_3.YTickLabelMode='auto';
            app.UIAxes7_3.ZTickLabelMode='auto';
            app.UIAxes7_3.CameraPositionMode='auto';
            app.UIAxes7_3.CameraTargetMode='auto';
            app.UIAxes7_3.CameraUpVectorMode='auto';
            
            app.UIAxes7_4.DataAspectRatioMode='auto';
            app.UIAxes7_4.PlotBoxAspectRatioMode='auto';
            app.UIAxes7_4.CLimMode='auto';
            app.UIAxes7_4.XLimMode='auto';
            app.UIAxes7_4.YLimMode='auto';
            app.UIAxes7_4.ZLimMode='auto';
            app.UIAxes7_4.GridAlphaMode='auto';
            app.UIAxes7_4.GridColorMode='auto';
            app.UIAxes7_4.TickDirMode='auto';
            app.UIAxes7_4.XTickMode='auto';
            app.UIAxes7_4.YTickMode='auto';
            app.UIAxes7_4.ZTickMode='auto';
            app.UIAxes7_4.XTickLabelMode='auto';
            app.UIAxes7_4.YTickLabelMode='auto';
            app.UIAxes7_4.ZTickLabelMode='auto';
            app.UIAxes7_4.CameraPositionMode='auto';
            app.UIAxes7_4.CameraTargetMode='auto';
            app.UIAxes7_4.CameraUpVectorMode='auto';
            
            
            p=plot(app.UIAxes5,G,'Layout','force','NodeColor',c,'LineStyle', '--','LineWidth', 0.05);
            nn=1:1:Nodes;
            highlight(p,nn,'MarkerSize',8)
            
            
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            value = app.MinSlider.Value;
            if value > app.MaxSlider.Value
                app.MaxSlider.Value=value
            end
            
        end

        % Callback function
        function RunButtonValueChanged(app, event)


        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            app.Ready=0;
            
            app.SimulationOffLampLabel.Text = 'Simulation On';
            app.SimStat.Color = [0 1 0];
            app.SimS.Text = 'Data Upload';
            load(app.NetworknameEditField.Value)
            app.SimS.Text = 'Parameters setting';
            Nodes=numnodes(G);
            PositiveAccRumor = zeros(Nodes,1);
            AccRumor = zeros(Nodes,1);
            IndividualOpinion= zeros(Nodes,1);
            n=Nodes;
            c2=randi([0,0],n,1);
            c2(:,2)=randi([0,0],n,1);
            c2(:,3)=randi([1,1],n,1);


            InfectedNodes(1)=1;
            
            c=randi([0,0],n,1);
            c(:,2)=randi([0,0],n,1);
            c(:,3)=randi([1,1],n,1);
            L=randperm(Nodes,app.IntSpeaders.Value);
            NodeNumAcc = zeros(Nodes,1);
            Time=0.125;
            NbrSupport(1)=0;
            for i=1:length(L)
                x=L(i);
                Infected(x)=1;
                
                NodeNumAcc(x)=Time;
                c(x,:)=[1,0,0];
                if randi([0,1],1,1)==1
                IndividualOpinion(x)=2;
                c2(x,:)=[0,1,0];
                NbrSupport(1)= NbrSupport(1)+1;
                else
                IndividualOpinion(x)=1;
                c2(x,:)=[1,0,0];
                end
                InfectedNodes(end+1)=x;
                
            end
            
            InfectedNodes(1)=[];
            p=plot(app.UIAxes5,G,'Layout','force','NodeColor',c,'LineStyle', '--','LineWidth', 0.05);
            ColorCode=[0,1,0;0,0.900000000000000,0;0,0.800000000000000,0;0,0.700000000000000,0;0,0.600000000000000,0;0,0.500000000000000,0;0,0.400000000000000,0;0,0.300000000000000,0;0,0.200000000000000,0;0,0.100000000000000,0;0.100000000000000,0,0;0.200000000000000,0,0;0.300000000000000,0,0;0.400000000000000,0,0;0.500000000000000,0,0;0.600000000000000,0,0;0.700000000000000,0,0;0.800000000000000,0,0;0.900000000000000,0,0;1,0,0];
            nn=1:1:Nodes;
            highlight(p,nn,'MarkerSize',8)
            
            NodeFR= (app.MinSlider_2.Value)+((app.MaxSlider_2.Value)-(app.MinSlider_2.Value))*rand(Nodes,1);
            NodeDes=(app.MinSlider.Value)+(app.MaxSlider.Value-app.MinSlider.Value)*rand(Nodes,1);
            NodeHe= (app.MinSlider_3.Value)+((app.MaxSlider_3.Value)-(app.MinSlider_3.Value))*rand(Nodes,1);
            NodeDis=(app.DisMin.Value)+(app.DisMax.Value-app.DisMin.Value)*rand(Nodes,1);
            RumorPopularity(1)=0;
            NodeNumAcc(1)=0;
            NbrSpreaders(1)=0;
            
            
            count=2;
            
            NodeAttraction= zeros(Nodes,1);
            app.MaxSlider_3Label.Text = 'Simulation In progress';
            while length(InfectedNodes)>0
                Spreaders=0;
                for ll=length(InfectedNodes):-1:1
                    i=InfectedNodes(ll);
                    a=abs( exp(-(Time-NodeNumAcc(i))*NodeDes(i))*sin((NodeFR(i)*(Time-NodeNumAcc(i)))-(NodeHe(i))));
                    
                    if exp(-(Time-NodeNumAcc(i))*NodeDes(i)) < 0.15
                        InfectedNodes(ll)=[];
                        NodeAttraction(i)=0;
                    else
                        ID=ceil(a/0.05);
                        c(i,:)=ColorCode(ID,:);
                        NodeAttraction(i)= a*degree(G,i);
                    end
                    
                    
                    send=0;
                    l=randi(100);
                    y=(a)*100;
                    
                    if( l <= y  )
                        send = 1;
                    end
                    
                    if send == 1 && degree(G,i) > 0
                        Spreaders=Spreaders+1;
                        IDSelectedNod=randperm(degree(G,i),floor(degree(G,i)*0.2));
                        Nei=neighbors(G,i);
                        for oo=1:length(IDSelectedNod)
                            ID= Nei(IDSelectedNod(oo));
                            l=randi(100);
                            p= (degree(G,i)/(degree(G,ID)+degree(G,i)))*100;
                            if(l <= p )
                                AccRumor(ID) = AccRumor(ID)+1;
                                if IndividualOpinion(i)==2
                                    PositiveAccRumor((ID))= PositiveAccRumor((ID))+1;
                                end
                                
                                if NodeNumAcc(ID) == 0
                                    InfectedNodes(end+1)=ID;
                                    NodeNumAcc(ID)=Time;
                                    IndividualOpinion(ID)=IndividualOpinion(i);
                                    if IndividualOpinion(i)==1
                                        c2(ID,:)=[1,0,0];
                                    else
                                        c2(ID,:)=[0,1,0];
                                    end
                                else
                                    dd=(( PositiveAccRumor(ID)/AccRumor(ID))+ NodeDis(ID))/2;
                                    x=rand();
                                    if x < dd
                                        IndividualOpinion(ID)=2;
                                        c2(ID,:)=[0,1,0];
                                    else
                                        IndividualOpinion(ID)=1;
                                        c2(ID,:)=[1,0,0];
                                    end
                                end
                            end
                        end
                        
                    end


                end
                
                NumberInfectedNodes(count)=sum(NodeNumAcc(:)>0);
                RumorPopularity(count)=sum(NodeAttraction(:));
                NbrSpreaders(count)= Spreaders;
                NbrSupport(count)=sum(IndividualOpinion(:)==1);
                %                 if strcmp(app.SimulationOnlySwitch.Value,'Off')==1
                
                p=plot(app.UIAxes5,G,'Layout','force','NodeColor',c,'LineStyle', '--','LineWidth', 0.05);
                nn=1:1:Nodes;
                highlight(p,nn,'MarkerSize',8)
                
                
                p=plot(app.UIAxes6,G,'Layout','force','NodeColor',c2,'LineStyle', '--','LineWidth', 0.05);
                nn=1:1:Nodes;
                highlight(p,nn,'MarkerSize',8)
                
                x=0:1:count-1;
                
                plot(app.UIAxes7,x,RumorPopularity);
                
                
                plot(app.UIAxes7_2,x,NumberInfectedNodes);
                pause(app.SimTime.Value);
                
                plot(app.UIAxes7_3,x,NbrSpreaders);
                pause(app.SimTime.Value);
                
                plot(app.UIAxes7_4,x,NbrSupport);
                if app.Stop==1 % stop condition
                    app.Stop=0;
                    app.Ready=1;
                    break;
                end
                pause(app.SimTime.Value);
                
                %                 end
                Time=Time+0.125;
                count=count+1;
            end


            %             if strcmp(app.SimulationOnlySwitch.Value,'Off')==0
            %
            %
            %                 x=0:1:count-1-1;
            %                 if strcmp(app.KeeppreviousresultsSwitch.Value,'On')==1
            %                    hold(app.UIAxes2,'on');
            %                 end
            %                 plot(app.UIAxes2,x,RumorPopularity);
            %
            %                 if strcmp(app.KeeppreviousresultsSwitch.Value,'On')==1
            %                 hold(app.UIAxes2_2,'on');
            %                 end
            %                 plot(app.UIAxes2_2,x,NumberInfectedNodes);
            %
            %
            %
            %                 if strcmp(app.KeeppreviousresultsSwitch.Value,'On')==1
            %                 hold(app.UIAxes2_3,'on');
            %                 end
            %                 plot(app.UIAxes2_3,x,NbrSpreaders);
            %
            %
            %                 if strcmp(app.KeeppreviousresultsSwitch.Value,'On')==1
            %                 hold(app.UIAxes2_4,'on');
            %                 end
            %                 plot(app.UIAxes2_4,x,NbrSupport);
            %                 pause(app.SimTime.Value);
            %             else
            %
            
            %
            %             end
            
            app.SimulationOffLampLabel.Text = 'Simulation Off';
            app.SimStat.Color = [1 0 0];
            app.SimS.Text = 'Simulation ended';
            
             app.Ready=1;
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            value = app.MaxSlider.Value;
            
            if value < app.MinSlider.Value
                app.MinSlider.Value=value;
            end
        end

        % Value changed function: MinSlider_2
        function MinSlider_2ValueChanged(app, event)
            value = app.MinSlider_2.Value;
            
            if value > app.MaxSlider_2.Value
                app.MaxSlider_2.Value=value;
            end
            
        end

        % Value changed function: MaxSlider_2
        function MaxSlider_2ValueChanged(app, event)
            value = app.MaxSlider_2.Value;
            if value < app.MinSlider_2.Value
                app.MinSlider_2.Value=value;
            end
        end

        % Value changed function: MinSlider_3
        function MinSlider_3ValueChanged(app, event)
            value = app.MinSlider_3.Value;
            if value > app.MaxSlider_3.Value
                app.MaxSlider_3.Value=value;
            end
        end

        % Value changed function: MaxSlider_3
        function MaxSlider_3ValueChanged(app, event)
            value = app.MaxSlider_3.Value;
            if value < app.MinSlider_3.Value
                app.MinSlider_3.Value=value;
            end
        end

        % Value changed function: DisMin
        function DisMinValueChanged(app, event)
            value = app.DisMin.Value;
            if value > app.DisMax.Value
                app.DisMax.Value=value;
            end
            
        end

        % Value changed function: DisMax
        function DisMaxValueChanged(app, event)
            value = app.DisMax.Value;
            if value < app.MinSlider_3.Value
                app.MinSlider_3.Value=value;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load(app.NetworknameEditField.Value);
            app.NumberofNodesEditField.Value=num2str(numedges(G));
            app.NumberofEdgeEditField.Value= num2str(numnodes(G));
            app.KEditField.Value=num2str(numnodes(G)/numedges(G));
            n=numnodes(G);
            Nodes=numnodes(G);
            c=randi([0,0],n,1);
            c(:,2)=randi([0,0],n,1);
            c(:,3)=randi([1,1],n,1);
            p=plot(app.UIAxes5,G,'Layout','force','NodeColor',c,'LineStyle', '--','LineWidth', 0.05);
            nn=1:1:Nodes;
            highlight(p,nn,'MarkerSize',8)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop =1;
           
            
        end

        % Button pushed function: RestButton
        function RestButtonPushed(app, event)
            app.Stop=1;
            clc;
            clear;
            app.Stop=0;
        end

        % Close request function: HISBmodelSimulatorUIFigure
        function HISBmodelSimulatorUIFigureCloseRequest(app, event)
            Stop=1;
          
            delete(app)
           
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HISBmodelSimulatorUIFigure = uifigure;
            app.HISBmodelSimulatorUIFigure.Name = 'HISBmodel Simulator';
            app.HISBmodelSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @HISBmodelSimulatorUIFigureCloseRequest, true);
            app.InitialParametersPanel = uipanel(app.HISBmodelSimulatorUIFigure);
            app.InitialParametersPanel.Title = 'Initial Parameters';
            app.SimTimeSliderLabel = uilabel(app.InitialParametersPanel);
            app.SimTimeSliderLabel.Text = 'SimTime';
            app.SimTime = uislider(app.InitialParametersPanel);
            app.SimTime.Limits = [0 2];
            app.SimTime.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.SimTime.Value = 0.1;
            app.InitialSpreadersEditFieldLabel = uilabel(app.InitialParametersPanel);
            app.InitialSpreadersEditFieldLabel.Text = 'Initial Spreaders';
            app.IntSpeaders = uieditfield(app.InitialParametersPanel, 'numeric');
            app.IntSpeaders.Limits = [0 5000];
            app.IntSpeaders.Value = 10;
            app.IndividualsbackgroundknowledgePanel = uipanel(app.InitialParametersPanel);
            app.IndividualsbackgroundknowledgePanel.Title = 'Individual''s background knowledge';
            app.MinSliderLabel = uilabel(app.IndividualsbackgroundknowledgePanel);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.IndividualsbackgroundknowledgePanel);
            app.MinSlider.Limits = [0 1];
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.Value = 0.2;
            app.MaxSliderLabel = uilabel(app.IndividualsbackgroundknowledgePanel);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.IndividualsbackgroundknowledgePanel);
            app.MaxSlider.Limits = [0 1];
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Value = 0.8;
            app.ForgettingremmemberingfactorPanel = uipanel(app.InitialParametersPanel);
            app.ForgettingremmemberingfactorPanel.Title = 'Forgetting-remmembering factor';
            app.MinSlider_2Label = uilabel(app.ForgettingremmemberingfactorPanel);
            app.MinSlider_2Label.Text = 'Min';
            app.MinSlider_2 = uislider(app.ForgettingremmemberingfactorPanel);
            app.MinSlider_2.Limits = [0 6];
            app.MinSlider_2.ValueChangedFcn = createCallbackFcn(app, @MinSlider_2ValueChanged, true);
            app.MinSlider_2.Value = 0.2;
            app.MaxSlider_2Label = uilabel(app.ForgettingremmemberingfactorPanel);
            app.MaxSlider_2Label.Text = 'Max';
            app.MaxSlider_2 = uislider(app.ForgettingremmemberingfactorPanel);
            app.MaxSlider_2.Limits = [0 6];
            app.MaxSlider_2.ValueChangedFcn = createCallbackFcn(app, @MaxSlider_2ValueChanged, true);
            app.MaxSlider_2.Value = 5;
            app.HesitatingfactorPanel = uipanel(app.InitialParametersPanel);
            app.HesitatingfactorPanel.Title = 'Hesitating factor';
            app.MinSlider_3Label = uilabel(app.HesitatingfactorPanel);
            app.MinSlider_3Label.Text = 'Min';
            app.MinSlider_3 = uislider(app.HesitatingfactorPanel);
            app.MinSlider_3.Limits = [0 1.6];
            app.MinSlider_3.ValueChangedFcn = createCallbackFcn(app, @MinSlider_3ValueChanged, true);
            app.MinSlider_3.Value = 0.2;
            app.MaxSlider_3Label = uilabel(app.HesitatingfactorPanel);
            app.MaxSlider_3Label.Text = 'Max';
            app.MaxSlider_3 = uislider(app.HesitatingfactorPanel);
            app.MaxSlider_3.Limits = [0 1.6];
            app.MaxSlider_3.ValueChangedFcn = createCallbackFcn(app, @MaxSlider_3ValueChanged, true);
            app.MaxSlider_3.Value = 0.2;
            app.subjectivejudgmentPanel = uipanel(app.InitialParametersPanel);
            app.subjectivejudgmentPanel.Title = 'subjective judgment';
            app.MinLabel = uilabel(app.subjectivejudgmentPanel);
            app.MinLabel.Text = 'Min';
            app.DisMin = uislider(app.subjectivejudgmentPanel);
            app.DisMin.Limits = [0 1];
            app.DisMin.ValueChangedFcn = createCallbackFcn(app, @DisMinValueChanged, true);
            app.DisMin.Value = 0.05;
            app.MaxLabel = uilabel(app.subjectivejudgmentPanel);
            app.MaxLabel.Text = 'Max';
            app.DisMax = uislider(app.subjectivejudgmentPanel);
            app.DisMax.Limits = [0 1];
            app.DisMax.ValueChangedFcn = createCallbackFcn(app, @DisMaxValueChanged, true);
            app.DisMax.Value = 0.8;
            app.SimulationOffLampLabel = uilabel(app.InitialParametersPanel);
            app.SimulationOffLampLabel.Text = 'Simulation Off';
            app.SimStat = uilamp(app.InitialParametersPanel);
            app.SimS = uilabel(app.HISBmodelSimulatorUIFigure);
            app.SimS.Text = 'Simulation Status';
            app.RunButton_2 = uibutton(app.HISBmodelSimulatorUIFigure, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.TabGroup = uitabgroup(app.HISBmodelSimulatorUIFigure);
            app.IndividualsattrctionTab = uitab(app.TabGroup);
            app.IndividualsattrctionTab.Title = 'Individuals'' attrction';
            app.UIAxes5 = uiaxes(app.IndividualsattrctionTab);
            app.IndividualsOpinionTab = uitab(app.TabGroup);
            app.IndividualsOpinionTab.Title = 'Individuals Opinion';
            app.UIAxes6 = uiaxes(app.IndividualsOpinionTab);
            app.UIAxes6.XLim = [0 1];
            app.UIAxes6.YLim = [0 1];
            app.UIAxes6.ZLim = [0 1];
            app.UIAxes6.CLim = [0 1];
            app.UIAxes6.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes6.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes6.ZTick = [0 0.5 1];
            app.DenyingOpinionLampLabel = uilabel(app.IndividualsOpinionTab);
            app.DenyingOpinionLampLabel.Text = 'Denying Opinion';
            app.DenyingOpinionLamp = uilamp(app.IndividualsOpinionTab);
            app.SupprottingopinionLampLabel = uilabel(app.IndividualsOpinionTab);
            app.SupprottingopinionLampLabel.Text = 'Supprotting opinion';
            app.SupprottingopinionLamp = uilamp(app.IndividualsOpinionTab);
            app.ResultsPanel = uipanel(app.HISBmodelSimulatorUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.UIAxes7 = uiaxes(app.ResultsPanel);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'Time')
            ylabel(app.UIAxes7, 'Individuals'' Attraction')
            app.UIAxes7.XLim = [0 1];
            app.UIAxes7.YLim = [0 1];
            app.UIAxes7.ZLim = [0 1];
            app.UIAxes7.CLim = [0 1];
            app.UIAxes7.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7.ZTick = [0 0.5 1];
            app.UIAxes7_2 = uiaxes(app.ResultsPanel);
            title(app.UIAxes7_2, 'Number of infected individuals')
            xlabel(app.UIAxes7_2, 'Time')
            ylabel(app.UIAxes7_2, 'Number of individuals')
            app.UIAxes7_2.XLim = [0 1];
            app.UIAxes7_2.YLim = [0 1];
            app.UIAxes7_2.ZLim = [0 1];
            app.UIAxes7_2.CLim = [0 1];
            app.UIAxes7_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7_2.ZTick = [0 0.5 1];
            app.UIAxes7_3 = uiaxes(app.ResultsPanel);
            title(app.UIAxes7_3, 'Number of spreaders')
            xlabel(app.UIAxes7_3, 'Time')
            ylabel(app.UIAxes7_3, 'Number of individuals')
            app.UIAxes7_3.XLim = [0 1];
            app.UIAxes7_3.YLim = [0 1];
            app.UIAxes7_3.ZLim = [0 1];
            app.UIAxes7_3.CLim = [0 1];
            app.UIAxes7_3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7_3.ZTick = [0 0.5 1];
            app.UIAxes7_4 = uiaxes(app.ResultsPanel);
            title(app.UIAxes7_4, 'Rumor popularity')
            xlabel(app.UIAxes7_4, 'Time')
            ylabel(app.UIAxes7_4, 'Number of individuals')
            app.UIAxes7_4.XLim = [0 1];
            app.UIAxes7_4.YLim = [0 1];
            app.UIAxes7_4.ZLim = [0 1];
            app.UIAxes7_4.CLim = [0 1];
            app.UIAxes7_4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7_4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes7_4.ZTick = [0 0.5 1];
            app.NetworkdetailPanel = uipanel(app.HISBmodelSimulatorUIFigure);
            app.NetworkdetailPanel.Title = 'Network detail';
            app.NumberofNodesEditFieldLabel = uilabel(app.NetworkdetailPanel);
            app.NumberofNodesEditFieldLabel.Enable = 'off';
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.NetworkdetailPanel, 'text');
            app.NumberofNodesEditField.Enable = 'off';
            app.NumberofEdgeEditFieldLabel = uilabel(app.NetworkdetailPanel);
            app.NumberofEdgeEditFieldLabel.Enable = 'off';
            app.NumberofEdgeEditFieldLabel.Text = 'Number of Edge';
            app.NumberofEdgeEditField = uieditfield(app.NetworkdetailPanel, 'text');
            app.NumberofEdgeEditField.Enable = 'off';
            app.KEditFieldLabel = uilabel(app.NetworkdetailPanel);
            app.KEditFieldLabel.Enable = 'off';
            app.KEditFieldLabel.Text = '<K>';
            app.KEditField = uieditfield(app.NetworkdetailPanel, 'text');
            app.KEditField.Enable = 'off';
            app.NetworknameEditFieldLabel = uilabel(app.HISBmodelSimulatorUIFigure);
            app.NetworknameEditFieldLabel.Text = 'Network name';
            app.NetworknameEditField = uieditfield(app.HISBmodelSimulatorUIFigure, 'text');
            app.NetworknameEditField.Value = 'G.mat';
            app.LoadButton = uibutton(app.HISBmodelSimulatorUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StopButton = uibutton(app.HISBmodelSimulatorUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RestButton = uibutton(app.HISBmodelSimulatorUIFigure, 'push');
            app.RestButton.ButtonPushedFcn = createCallbackFcn(app, @RestButtonPushed, true);
            app.RestButton.Text = 'Rest';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HISBmodelSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HISBmodelSimulatorUIFigure)
        end
    end
end